classdef ITEC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LeftPanel                     matlab.ui.container.Panel
        ITECLabel                     matlab.ui.control.Label
        OverviewButton                matlab.ui.control.Button
        ImportButton                  matlab.ui.control.Button
        GeneralParametersButton       matlab.ui.control.Button
        ProcessingParametersButton    matlab.ui.control.Button
        StartTrackingButton           matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        OverviewPanel                 matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        Label                         matlab.ui.control.Label
        ImportPanel                   matlab.ui.container.Panel
        importparameters              matlab.ui.control.CheckBox
        parasfileEditField            matlab.ui.control.EditField
        OneclickparameterimportcsvLabel  matlab.ui.control.Label
        parasfileButton               matlab.ui.control.Button
        SelectframerangeforexecutionemptyforallLabel  matlab.ui.control.Label
        SelectyouroutputpathLabel     matlab.ui.control.Label
        SelectyourdatafolderLabel     matlab.ui.control.Label
        endSpinner                    matlab.ui.control.Spinner
        endSpinnerLabel               matlab.ui.control.Label
        startSpinner                  matlab.ui.control.Spinner
        startSpinnerLabel             matlab.ui.control.Label
        savefolderEditField           matlab.ui.control.EditField
        datafolderEditField           matlab.ui.control.EditField
        savefolderButton              matlab.ui.control.Button
        datafolderButton              matlab.ui.control.Button
        GeneralParametersPanel        matlab.ui.container.Panel
        IntensitylowerboundEditField  matlab.ui.control.NumericEditField
        IntensitylowerboundEditFieldLabel  matlab.ui.control.Label
        FilterfactorSlider            matlab.ui.control.Slider
        FilterfactorSliderLabel       matlab.ui.control.Label
        SmoothingLabel                matlab.ui.control.Label
        GrayscaleLabel                matlab.ui.control.Label
        CellsizepixelsLabel           matlab.ui.control.Label
        ResolutionLabel               matlab.ui.control.Label
        zxyratioEditField             matlab.ui.control.NumericEditField
        zxyratioEditFieldLabel        matlab.ui.control.Label
        IntensityupperboundEditField  matlab.ui.control.NumericEditField
        IntensityupperboundEditFieldLabel  matlab.ui.control.Label
        maxEditField                  matlab.ui.control.NumericEditField
        maxEditFieldLabel             matlab.ui.control.Label
        minEditField                  matlab.ui.control.NumericEditField
        minEditFieldLabel             matlab.ui.control.Label
        BackgroundintensityEditField  matlab.ui.control.NumericEditField
        BackgroundintensityEditFieldLabel  matlab.ui.control.Label
        DownsamplingratiorescalexyforaccelerationSpinner  matlab.ui.control.Spinner
        DownsamplingratiorescalexyforaccelerationSpinnerLabel  matlab.ui.control.Label
        ProcessingparametersPanel     matlab.ui.container.Panel
        AdvancedparametersfortrackingCheckBox  matlab.ui.control.CheckBox
        AdvancedparametersforsegmentationCheckBox  matlab.ui.control.CheckBox
        UsemotionflowestimationButtonGroup  matlab.ui.container.ButtonGroup
        noButton_4                    matlab.ui.control.RadioButton
        yesButton_4                   matlab.ui.control.RadioButton
        SavevisualizationresultstifButtonGroup  matlab.ui.container.ButtonGroup
        noButton_3                    matlab.ui.control.RadioButton
        yesButton_3                   matlab.ui.control.RadioButton
        SaveaugmentedsegresultsmatButtonGroup  matlab.ui.container.ButtonGroup
        noButton_2                    matlab.ui.control.RadioButton
        yesButton_2                   matlab.ui.control.RadioButton
        maxIterSpinner                matlab.ui.control.Spinner
        maxIterSpinnerLabel           matlab.ui.control.Label
        maxdistanceEditField          matlab.ui.control.NumericEditField
        maxdistanceEditFieldLabel     matlab.ui.control.Label
        divisionfactorincreaseittodetectmoredivisionsSpinner  matlab.ui.control.Spinner
        divisionfactorincreaseittodetectmoredivisionsSpinnerLabel  matlab.ui.control.Label
        TrackingLabel                 matlab.ui.control.Label
        SegmentationLabel             matlab.ui.control.Label
        Theminimumgrayscalevalueofbackgroundusually0Label_4  matlab.ui.control.Label
        foreThresLabel                matlab.ui.control.Label
        curveThresLabel               matlab.ui.control.Label
        IntensitydifferenceLabel      matlab.ui.control.Label
        contrastintensityEditField    matlab.ui.control.NumericEditField
        smFactorSpinner               matlab.ui.control.Spinner
        forezThresSpinner             matlab.ui.control.Spinner
        curvaturethresholdSpinner     matlab.ui.control.Spinner
        StartTrackingPanel            matlab.ui.container.Panel
        LogLabel                      matlab.ui.control.Label
        tostarttrackingLabel          matlab.ui.control.Label
        PleaseclickLabel              matlab.ui.control.Label
        savefileEditField             matlab.ui.control.EditField
        saveFileLabel                 matlab.ui.control.Label
        savefileButton                matlab.ui.control.Button
        saveSelect                    matlab.ui.control.CheckBox
        OutputTextArea                matlab.ui.control.TextArea
        SaveButton                    matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
    end

     
    methods (Access = private)
        
        function logOutput(app, message)
            currentText = app.OutputTextArea.Value;
            if isempty(currentText)
                app.OutputTextArea.Value = {message};
            else
                app.OutputTextArea.Value = [currentText; {message}];
            end
            app.OutputTextArea.scroll('bottom');
        end
        
        function scaleUIComponents(app)
            ReferenceSize = [640,480];
            screenSize = get(groot, 'ScreenSize'); 
            availableWidth = screenSize(3) * 0.7;
            availableHeight = screenSize(4) * 0.7;
            
            widthScale = availableWidth / ReferenceSize(1);
            heightScale = availableHeight / ReferenceSize(2);
            finalScale = min(widthScale, heightScale);
            appWidth = ReferenceSize(1) * finalScale;
            appHeight = ReferenceSize(2) * finalScale;
            
            appX = (screenSize(3) - appWidth) / 2;
            appY = (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position = [appX, appY, appWidth, appHeight];
            
            leftPanelWidth = 171 * finalScale;  

            app.LeftPanel.Position = [1, 1, leftPanelWidth, appHeight];   
            rightPanelWidth = appWidth - leftPanelWidth;
            app.RightPanel.Position = [leftPanelWidth+1, 1, rightPanelWidth-1, appHeight];  
            
            finalScale = finalScale * 0.98;
            scaleChild(app.LeftPanel, finalScale);
            scaleChild(app.RightPanel, finalScale);
            
            function scaleChild(parent, scale)
                children = parent.Children;
                
                for i = 1:numel(children)
                    child = children(i);
                    
                    if isprop(child, 'Position')
                        pos = child.Position;
                        newPos = [pos(1)*scale, pos(2)*scale, pos(3)*scale, pos(4)*scale];
                        warningState = warning;                       
                        warning('off', 'all');
                        
                        try
                            child.Position = newPos;
                        catch
                            child.Position = [newPos(1), newPos(2), pos(3), pos(4)];
                        end
                        
                        warning(warningState);
                    end
                    
                    if isprop(child, 'FontSize') && ~isempty(child.FontSize)
                        try
                            child.FontSize = child.FontSize * scale;
                        catch
                            continue;
                        end
                    end
                    
                    if isprop(child, 'Children') && numel(child.Children) > 0
                        scaleChild(child, scale);
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.UIFigure.Name = 'ITEC';
            app.UIFigure.Resize = 'off'; 
            app.UIFigure.Visible = 'on'; 
            app.scaleUIComponents(); 
        end

        % Button pushed function: OverviewButton
        function OverviewButtonPushed(app, event)
            app.OverviewPanel.Visible = 'on';
            app.ImportPanel.Visible = 'off';
            app.GeneralParametersPanel.Visible = 'off';
            app.ProcessingparametersPanel.Visible = 'off';
            app.StartTrackingPanel.Visible = 'off';
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            app.OverviewPanel.Visible = 'off';
            app.ImportPanel.Visible = 'on';
            app.GeneralParametersPanel.Visible = 'off';
            app.ProcessingparametersPanel.Visible = 'off';
            app.StartTrackingPanel.Visible = 'off';
        end

        % Button pushed function: GeneralParametersButton
        function BasicParametersButtonPushed(app, event)
            app.OverviewPanel.Visible = 'off';
            app.ImportPanel.Visible = 'off';
            app.GeneralParametersPanel.Visible = 'on';
            app.ProcessingparametersPanel.Visible = 'off';
            app.StartTrackingPanel.Visible = 'off';
        end

        % Button pushed function: ProcessingParametersButton
        function ProcessingParametersButtonPushed(app, event)
            app.OverviewPanel.Visible = 'off';
            app.ImportPanel.Visible = 'off';
            app.GeneralParametersPanel.Visible = 'off';
            app.ProcessingparametersPanel.Visible = 'on';
            app.StartTrackingPanel.Visible = 'off';
        end

        % Callback function
        function ContactusButtonPushed(app, event)
            app.OverviewPanel.Visible = 'off';
            app.ImportPanel.Visible = 'off';
            app.GeneralParametersPanel.Visible = 'off';
            app.ProcessingparametersPanel.Visible = 'off';
            app.StartTrackingPanel.Visible = 'off';
        end

        % Button pushed function: datafolderButton
        function datafolderButtonPushed(app, event)
            data_path = uigetdir;
            if data_path~=0
                set(app.datafolderEditField,'Value',data_path);
            end
        end

        % Button pushed function: savefolderButton
        function savefolderButtonPushed(app, event)
            result_path = uigetdir;
            if result_path ~= 0
                set(app.savefolderEditField,'Value',result_path);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            SaveparasfileButtonPushed(app, event);
            app.logOutput(' Start running...');
            app.logOutput(' For more process prompts, please to the log file.');
            drawnow;
            addpath(genpath('./'));
            csv_path = 'params.csv';
            [~, paras_instSeg, paras_tracking] = set_paras(csv_path);
            app.logOutput(' Start Segmentation...');
            drawnow;
            diary('ITEC_log.txt') 
            InstanceSegmentation(paras_instSeg);
            app.logOutput(' Start Tracking...');
            drawnow;
            Tracking(paras_tracking);
            app.logOutput(' Execution completed!');
            diary off
        end

        % Button pushed function: parasfileButton
        function parasfileButtonPushed(app, event)
             [csv_name,csv_path] = uigetfile('*.csv');
             if csv_path ~= 0
                 set(app.parasfileEditField,'Value',fullfile(csv_path,csv_name));
                 addpath(genpath('./'));
                 [paras, paras_instSeg, paras_tracking] = set_paras(fullfile(csv_path,csv_name));
                 % start panel
                 set(app.datafolderEditField,'Value',paras.data_path);
                 set(app.savefolderEditField,'Value',paras.result_path);
                 set(app.startSpinner,'Value',paras.start_tm);
                 set(app.endSpinner,'Value',paras.end_tm);
                 % general parameters
                 set(app.zxyratioEditField,'Value',paras.z_resolution);
                 set(app.maxEditField,'Value',paras.maxSize);
                 set(app.minEditField,'Value',paras.minSize);
                 set(app.DownsamplingratiorescalexyforaccelerationSpinner,'Value',paras.xy_downSampleScale);
                 
                 set(app.IntensityupperboundEditField,'Value',paras.scale_term);
                 set(app.IntensitylowerboundEditField,'Value',paras_instSeg.clipping);
                 set(app.BackgroundintensityEditField,'Value',paras.bgIntensity);

                 set(app.FilterfactorSlider,'Value',paras.filter_sigma(1));

                 % processing parameters
                 % segmentation basic
                 if paras_instSeg.visualization
                     app.SavevisualizationresultstifButtonGroup.SelectedObject = app.yesButton_3;
                 else
                     app.SavevisualizationresultstifButtonGroup.SelectedObject = app.noButton_3;
                 end
                 set(app.smFactorSpinner,'Value',paras_instSeg.smFactorLst);
                 % segmentation advanced
                 set(app.curvaturethresholdSpinner,'Value',paras_instSeg.curveThres);
                 set(app.forezThresSpinner,'Value',paras_instSeg.forezThres);
                 set(app.contrastintensityEditField,'Value',paras_instSeg.diffIntensity);
                 
                 % tracking basic
                 set(app.divisionfactorincreaseittodetectmoredivisionsSpinner,'Value',paras_tracking.division_thres);
                 % tracking advanced
                 set(app.maxdistanceEditField,'Value',paras_tracking.max_dist);
                 if paras_tracking.useMotionFlow
                     app.UsemotionflowestimationButtonGroup.SelectedObject = app.yesButton_4;
                 else
                     app.UsemotionflowestimationButtonGroup.SelectedObject = app.noButton_4;
                 end
                 if paras_tracking.saveAllResults
                     app.SaveaugmentedsegresultsmatButtonGroup.SelectedObject = app.yesButton_2;
                 else
                     app.SaveaugmentedsegresultsmatButtonGroup.SelectedObject = app.noButton_2;
                 end
             end
        end

        % Button pushed function: SaveButton
        function SaveparasfileButtonPushed(app, event)
            % start panel
            addpath(genpath('./src_code_cellSegment'));
            addpath(genpath('./src_code_cellTracker'));
            addpath(genpath('./src_code_visualization'));
            addpath(genpath('./src_code_function'));
            paras_basic.data_path = app.datafolderEditField.Value;  
            paras_basic.result_path = app.savefolderEditField.Value;
            paras_basic.start_tm = app.startSpinner.Value;
            paras_basic.end_tm = app.endSpinner.Value;
            % general parameters
            paras_basic.z_resolution = app.zxyratioEditField.Value;
            paras_basic.minSize = app.minEditField.Value;      
            paras_basic.maxSize = app.maxEditField.Value;
            paras_basic.xy_downSampleScale = app.DownsamplingratiorescalexyforaccelerationSpinner.Value;

            paras_basic.scale_term = app.IntensityupperboundEditField.Value;
            paras_basic.clipping = app.IntensitylowerboundEditField.Value;   
            paras_basic.bgIntensity = app.BackgroundintensityEditField.Value;
            
            paras_basic.filter_sigma = app.FilterfactorSlider.Value;
            % segmentation parameters
            if app.SavevisualizationresultstifButtonGroup.SelectedObject == app.yesButton_3
                paras_seg.visualization = true;
            elseif app.SavevisualizationresultstifButtonGroup.SelectedObject == app.noButton_3
                paras_seg.visualization = false;
            end
            paras_seg.smFactorLst = app.smFactorSpinner.Value;  
            paras_seg.curveThres = app.curvaturethresholdSpinner.Value;  
            paras_seg.forezThres = app.forezThresSpinner.Value;
            paras_seg.diffIntensity = app.contrastintensityEditField.Value;
            
            % tracking parameters
            paras_tra.maxIter = app.maxIterSpinner.Value;
            paras_tra.division_thres = app.divisionfactorincreaseittodetectmoredivisionsSpinner.Value;  
            if app.SaveaugmentedsegresultsmatButtonGroup.SelectedObject == app.yesButton_2
                paras_tra.saveAllResults = true;
            elseif app.SaveaugmentedsegresultsmatButtonGroup.SelectedObject == app.noButton_2
                paras_tra.saveAllResults = false;
            end
            if app.UsemotionflowestimationButtonGroup.SelectedObject == app.yesButton_4
                paras_tra.useMotionFlow = true;
            elseif app.UsemotionflowestimationButtonGroup.SelectedObject == app.noButton_4
                paras_tra.useMotionFlow = false;
            end
            paras_tra.max_dist = app.maxdistanceEditField.Value;  
            
            if ~isempty(app.savefileEditField.Value)
                save_path = fullfile(app.savefileEditField.Value,'params.csv');
            else
                save_path = 'params.csv';
            end
            save_paras(paras_basic,paras_seg,paras_tra,save_path);
        end

        % Value changed function: importparameters
        function importparametersValueChanged(app, event)
            value = app.importparameters.Value;
            if value == true
                app.OneclickparameterimportcsvLabel.Visible = true;
                app.parasfileButton.Visible = true;
                app.parasfileEditField.Visible = true;
            else
                app.OneclickparameterimportcsvLabel.Visible = false;
                app.parasfileButton.Visible = false;
                app.parasfileEditField.Visible = false;                
            end
        end

        % Button pushed function: StartTrackingButton
        function StartTrackingButtonPushed(app, event)
            app.OverviewPanel.Visible = 'off';
            app.ImportPanel.Visible = 'off';
            app.GeneralParametersPanel.Visible = 'off';
            app.ProcessingparametersPanel.Visible = 'off';
            app.StartTrackingPanel.Visible = 'on';
        end

        % Value changed function: AdvancedparametersforsegmentationCheckBox
        function AdvancedparametersforsegmentationCheckBoxValueChanged(app, event)
            value = app.AdvancedparametersforsegmentationCheckBox.Value;
            if value == true
                app.curvaturethresholdSpinner.Visible = true;
                app.curveThresLabel.Visible = true;
                app.forezThresSpinner.Visible = true;
                app.foreThresLabel.Visible = true;
                app.contrastintensityEditField.Visible = true;
                app.IntensitydifferenceLabel.Visible = true;
            else
                app.curvaturethresholdSpinner.Visible = false;
                app.curveThresLabel.Visible = false;
                app.forezThresSpinner.Visible = false;
                app.foreThresLabel.Visible = false;
                app.contrastintensityEditField.Visible = false; 
                app.IntensitydifferenceLabel.Visible = false;
            end
        end

        % Value changed function: AdvancedparametersfortrackingCheckBox
        function AdvancedparametersfortrackingCheckBoxValueChanged(app, event)
            value = app.AdvancedparametersfortrackingCheckBox.Value;
            if value == true
                app.SaveaugmentedsegresultsmatButtonGroup.Visible = true;
                app.UsemotionflowestimationButtonGroup.Visible = true;
                app.maxdistanceEditField.Visible = true;
                app.maxdistanceEditFieldLabel.Visible = true;
            else
                app.SaveaugmentedsegresultsmatButtonGroup.Visible = false;
                app.UsemotionflowestimationButtonGroup.Visible = false;
                app.maxdistanceEditField.Visible = false;
                app.maxdistanceEditFieldLabel.Visible = false;              
            end
        end

        % Value changed function: saveSelect
        function saveSelectValueChanged(app, event)
            value = app.saveSelect.Value;
            if value == true
                app.saveFileLabel.Visible = true;
                app.savefileButton.Visible = true;
                app.SaveButton.Visible = true;
                app.savefileEditField.Visible = true;
            else
                app.saveFileLabel.Visible = false;
                app.savefileButton.Visible = false;
                app.SaveButton.Visible = false;
                app.savefileEditField.Visible = false;            
            end
        end

        % Button pushed function: savefileButton
        function savefileButtonPushed(app, event)
            save_path = uigetdir;
            if save_path ~= 0
                set(app.savefileEditField,'Value',save_path);
            end
            
        end

        % Callback function
        function GeneralParametersButtonPushed(app, event)
            app.OverviewPanel.Visible = 'off';
            app.ImportPanel.Visible = 'off';
            app.GeneralParametersPanel.Visible = 'on';
            app.ProcessingparametersPanel.Visible = 'off';
            app.StartTrackingPanel.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RightPanel = uipanel(app.UIFigure);
            app.StartTrackingPanel = uipanel(app.RightPanel);
            app.StartTrackingPanel.Title = ' Start Tracking';
            app.RunButton = uibutton(app.StartTrackingPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SaveButton = uibutton(app.StartTrackingPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveparasfileButtonPushed, true);
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.OutputTextArea = uitextarea(app.StartTrackingPanel);
            app.OutputTextArea.Editable = 'off';
            app.saveSelect = uicheckbox(app.StartTrackingPanel);
            app.saveSelect.ValueChangedFcn = createCallbackFcn(app, @saveSelectValueChanged, true);
            app.saveSelect.Text = ' I want to save my parameter file.';
            app.savefileButton = uibutton(app.StartTrackingPanel, 'push');
            app.savefileButton.ButtonPushedFcn = createCallbackFcn(app, @savefileButtonPushed, true);
            app.savefileButton.Visible = 'off';
            app.savefileButton.Text = '...';
            app.saveFileLabel = uilabel(app.StartTrackingPanel);
            app.saveFileLabel.Visible = 'off';
            app.saveFileLabel.Text = 'Please select save path and click';
            app.savefileEditField = uieditfield(app.StartTrackingPanel, 'text');
            app.savefileEditField.Visible = 'off';
            app.PleaseclickLabel = uilabel(app.StartTrackingPanel);
            app.PleaseclickLabel.Text = 'Please click';
            app.tostarttrackingLabel = uilabel(app.StartTrackingPanel);
            app.tostarttrackingLabel.Text = 'to start tracking!';
            app.LogLabel = uilabel(app.StartTrackingPanel);
            app.LogLabel.Text = 'Log';
            app.ProcessingparametersPanel = uipanel(app.RightPanel);
            app.ProcessingparametersPanel.Title = ' Processing parameters';
            app.curvaturethresholdSpinner = uispinner(app.ProcessingparametersPanel);
            app.curvaturethresholdSpinner.Step = 0.5;
            app.curvaturethresholdSpinner.Visible = 'off';
            app.curvaturethresholdSpinner.Value = -5;
            app.forezThresSpinner = uispinner(app.ProcessingparametersPanel);
            app.forezThresSpinner.Step = 0.5;
            app.forezThresSpinner.Visible = 'off';
            app.forezThresSpinner.Value = 3;
            app.smFactorSpinner = uispinner(app.ProcessingparametersPanel);
            app.smFactorSpinner.Step = 0.5;
            app.smFactorSpinner.Limits = [1e-07 Inf];
            app.smFactorSpinner.Value = 2;
            app.contrastintensityEditField = uieditfield(app.ProcessingparametersPanel, 'numeric');
            app.contrastintensityEditField.Limits = [0 Inf];
            app.contrastintensityEditField.Visible = 'off';
            app.IntensitydifferenceLabel = uilabel(app.ProcessingparametersPanel);
            app.IntensitydifferenceLabel.Visible = 'off';
            app.IntensitydifferenceLabel.Text = 'Intensity difference';
            app.curveThresLabel = uilabel(app.ProcessingparametersPanel);
            app.curveThresLabel.Visible = 'off';
            app.curveThresLabel.Text = 'curveThres';
            app.foreThresLabel = uilabel(app.ProcessingparametersPanel);
            app.foreThresLabel.Visible = 'off';
            app.foreThresLabel.Text = 'foreThres';
            app.Theminimumgrayscalevalueofbackgroundusually0Label_4 = uilabel(app.ProcessingparametersPanel);
            app.Theminimumgrayscalevalueofbackgroundusually0Label_4.Text = 'smFactor (increase it to prevent over-segmentation)';
            app.SegmentationLabel = uilabel(app.ProcessingparametersPanel);
            app.SegmentationLabel.Text = '------------------------------ Segmentation ------------------------------';
            app.TrackingLabel = uilabel(app.ProcessingparametersPanel);
            app.TrackingLabel.Text = '------------------------------ Tracking ------------------------------';
            app.divisionfactorincreaseittodetectmoredivisionsSpinnerLabel = uilabel(app.ProcessingparametersPanel);
            app.divisionfactorincreaseittodetectmoredivisionsSpinnerLabel.Text = 'division factor (increase it to detect more divisions)';
            app.divisionfactorincreaseittodetectmoredivisionsSpinner = uispinner(app.ProcessingparametersPanel);
            app.divisionfactorincreaseittodetectmoredivisionsSpinner.Step = 0.01;
            app.divisionfactorincreaseittodetectmoredivisionsSpinner.Limits = [0 1];
            app.divisionfactorincreaseittodetectmoredivisionsSpinner.Value = 0.95;
            app.maxdistanceEditFieldLabel = uilabel(app.ProcessingparametersPanel);
            app.maxdistanceEditFieldLabel.Visible = 'off';
            app.maxdistanceEditFieldLabel.Text = 'max distance';
            app.maxdistanceEditField = uieditfield(app.ProcessingparametersPanel, 'numeric');
            app.maxdistanceEditField.Limits = [0 Inf];
            app.maxdistanceEditField.Visible = 'off';
            app.maxdistanceEditField.Value = 50;
            app.maxIterSpinnerLabel = uilabel(app.ProcessingparametersPanel);
            app.maxIterSpinnerLabel.Text = 'maxIter';
            app.maxIterSpinner = uispinner(app.ProcessingparametersPanel);
            app.maxIterSpinner.Limits = [1 Inf];
            app.maxIterSpinner.Value = 5;
            app.SaveaugmentedsegresultsmatButtonGroup = uibuttongroup(app.ProcessingparametersPanel);
            app.SaveaugmentedsegresultsmatButtonGroup.Title = 'Save augmented seg. results? (.mat)';
            app.SaveaugmentedsegresultsmatButtonGroup.Visible = 'off';
            app.yesButton_2 = uiradiobutton(app.SaveaugmentedsegresultsmatButtonGroup);
            app.yesButton_2.Text = 'yes';
            app.yesButton_2.Value = true;
            app.noButton_2 = uiradiobutton(app.SaveaugmentedsegresultsmatButtonGroup);
            app.noButton_2.Text = 'no';
            app.SavevisualizationresultstifButtonGroup = uibuttongroup(app.ProcessingparametersPanel);
            app.SavevisualizationresultstifButtonGroup.Title = 'Save visualization results (.tif)';
            app.yesButton_3 = uiradiobutton(app.SavevisualizationresultstifButtonGroup);
            app.yesButton_3.Text = 'yes';
            app.yesButton_3.Value = true;
            app.noButton_3 = uiradiobutton(app.SavevisualizationresultstifButtonGroup);
            app.noButton_3.Text = 'no';
            app.UsemotionflowestimationButtonGroup = uibuttongroup(app.ProcessingparametersPanel);
            app.UsemotionflowestimationButtonGroup.Title = 'Use motion flow estimation?';
            app.UsemotionflowestimationButtonGroup.Visible = 'off';
            app.yesButton_4 = uiradiobutton(app.UsemotionflowestimationButtonGroup);
            app.yesButton_4.Text = 'yes';
            app.yesButton_4.Value = true;
            app.noButton_4 = uiradiobutton(app.UsemotionflowestimationButtonGroup);
            app.noButton_4.Text = 'no';
            app.AdvancedparametersforsegmentationCheckBox = uicheckbox(app.ProcessingparametersPanel);
            app.AdvancedparametersforsegmentationCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedparametersforsegmentationCheckBoxValueChanged, true);
            app.AdvancedparametersforsegmentationCheckBox.Text = 'Advanced parameters for segmentation';
            app.AdvancedparametersfortrackingCheckBox = uicheckbox(app.ProcessingparametersPanel);
            app.AdvancedparametersfortrackingCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedparametersfortrackingCheckBoxValueChanged, true);
            app.AdvancedparametersfortrackingCheckBox.Text = 'Advanced parameters for tracking';
            app.GeneralParametersPanel = uipanel(app.RightPanel);
            app.GeneralParametersPanel.Title = ' General Parameters';
            app.DownsamplingratiorescalexyforaccelerationSpinnerLabel = uilabel(app.GeneralParametersPanel);
            app.DownsamplingratiorescalexyforaccelerationSpinnerLabel.Text = 'Downsampling ratio (rescale x&y for acceleration)';
            app.DownsamplingratiorescalexyforaccelerationSpinner = uispinner(app.GeneralParametersPanel);
            app.DownsamplingratiorescalexyforaccelerationSpinner.Limits = [1 Inf];
            app.DownsamplingratiorescalexyforaccelerationSpinner.Value = 1;
            app.BackgroundintensityEditFieldLabel = uilabel(app.GeneralParametersPanel);
            app.BackgroundintensityEditFieldLabel.Text = 'Background intensity';
            app.BackgroundintensityEditField = uieditfield(app.GeneralParametersPanel, 'numeric');
            app.minEditFieldLabel = uilabel(app.GeneralParametersPanel);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.GeneralParametersPanel, 'numeric');
            app.minEditField.Limits = [0 Inf];
            app.maxEditFieldLabel = uilabel(app.GeneralParametersPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.GeneralParametersPanel, 'numeric');
            app.maxEditField.Limits = [0 Inf];
            app.maxEditField.Value = 10000;
            app.IntensityupperboundEditFieldLabel = uilabel(app.GeneralParametersPanel);
            app.IntensityupperboundEditFieldLabel.Text = 'Intensity upper bound';
            app.IntensityupperboundEditField = uieditfield(app.GeneralParametersPanel, 'numeric');
            app.IntensityupperboundEditField.Limits = [0 Inf];
            app.IntensityupperboundEditField.Value = 255;
            app.zxyratioEditFieldLabel = uilabel(app.GeneralParametersPanel);
            app.zxyratioEditFieldLabel.Text = 'z-x/y ratio';
            app.zxyratioEditField = uieditfield(app.GeneralParametersPanel, 'numeric');
            app.zxyratioEditField.Limits = [0 Inf];
            app.zxyratioEditField.Value = 1;
            app.ResolutionLabel = uilabel(app.GeneralParametersPanel);
            app.ResolutionLabel.Text = '------------------------------------- Resolution ------------------------------------- ';
            app.CellsizepixelsLabel = uilabel(app.GeneralParametersPanel);
            app.CellsizepixelsLabel.Text = 'Cell size (pixels)';
            app.GrayscaleLabel = uilabel(app.GeneralParametersPanel);
            app.GrayscaleLabel.Text = '------------------------------------- Grayscale ------------------------------------- ';
            app.SmoothingLabel = uilabel(app.GeneralParametersPanel);
            app.SmoothingLabel.Text = '------------------------------------- Smoothing ------------------------------------- ';
            app.FilterfactorSliderLabel = uilabel(app.GeneralParametersPanel);
            app.FilterfactorSliderLabel.Text = 'Filter factor';
            app.FilterfactorSlider = uislider(app.GeneralParametersPanel);
            app.FilterfactorSlider.Limits = [0 5];
            app.FilterfactorSlider.Value = 3;
            app.IntensitylowerboundEditFieldLabel = uilabel(app.GeneralParametersPanel);
            app.IntensitylowerboundEditFieldLabel.Text = 'Intensity lower bound';
            app.IntensitylowerboundEditField = uieditfield(app.GeneralParametersPanel, 'numeric');
            app.IntensitylowerboundEditField.Limits = [0 Inf];
            app.ImportPanel = uipanel(app.RightPanel);
            app.ImportPanel.Title = ' Import';
            app.datafolderButton = uibutton(app.ImportPanel, 'push');
            app.datafolderButton.ButtonPushedFcn = createCallbackFcn(app, @datafolderButtonPushed, true);
            app.datafolderButton.Text = '...';
            app.savefolderButton = uibutton(app.ImportPanel, 'push');
            app.savefolderButton.ButtonPushedFcn = createCallbackFcn(app, @savefolderButtonPushed, true);
            app.savefolderButton.Text = '...';
            app.datafolderEditField = uieditfield(app.ImportPanel, 'text');
            app.savefolderEditField = uieditfield(app.ImportPanel, 'text');
            app.startSpinnerLabel = uilabel(app.ImportPanel);
            app.startSpinnerLabel.Text = 'start';
            app.startSpinner = uispinner(app.ImportPanel);
            app.startSpinner.Limits = [0 Inf];
            app.startSpinner.AllowEmpty = 'on';
            app.startSpinner.Value = [];
            app.endSpinnerLabel = uilabel(app.ImportPanel);
            app.endSpinnerLabel.Text = 'end';
            app.endSpinner = uispinner(app.ImportPanel);
            app.endSpinner.Limits = [0 Inf];
            app.endSpinner.AllowEmpty = 'on';
            app.endSpinner.Value = [];
            app.SelectyourdatafolderLabel = uilabel(app.ImportPanel);
            app.SelectyourdatafolderLabel.Text = 'Select your data folder';
            app.SelectyouroutputpathLabel = uilabel(app.ImportPanel);
            app.SelectyouroutputpathLabel.Text = 'Select your output path';
            app.SelectframerangeforexecutionemptyforallLabel = uilabel(app.ImportPanel);
            app.SelectframerangeforexecutionemptyforallLabel.Text = 'Select frame range for execution (empty for all)';
            app.parasfileButton = uibutton(app.ImportPanel, 'push');
            app.parasfileButton.ButtonPushedFcn = createCallbackFcn(app, @parasfileButtonPushed, true);
            app.parasfileButton.Visible = 'off';
            app.parasfileButton.Text = '...';
            app.OneclickparameterimportcsvLabel = uilabel(app.ImportPanel);
            app.OneclickparameterimportcsvLabel.Visible = 'off';
            app.OneclickparameterimportcsvLabel.Text = 'One-click parameter import (.csv)';
            app.parasfileEditField = uieditfield(app.ImportPanel, 'text');
            app.parasfileEditField.Visible = 'off';
            app.importparameters = uicheckbox(app.ImportPanel);
            app.importparameters.ValueChangedFcn = createCallbackFcn(app, @importparametersValueChanged, true);
            app.importparameters.Text = ' I want to import parameters directly from a parameter file.';
            app.OverviewPanel = uipanel(app.RightPanel);
            app.OverviewPanel.Title = ' Overview';
            app.Label = uilabel(app.OverviewPanel);
            app.Label.Text = 'ITEC is a high-precision, large-scale embryonic cell tracking platform dedicated to assisting developmental biologists in achieving whole-embryo lineage reconstruction.';
            app.Image = uiimage(app.OverviewPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'overview.png');
            app.LeftPanel = uipanel(app.UIFigure);
            app.StartTrackingButton = uibutton(app.LeftPanel, 'push');
            app.StartTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrackingButtonPushed, true);
            app.StartTrackingButton.Text = 'Start Tracking';
            app.ProcessingParametersButton = uibutton(app.LeftPanel, 'push');
            app.ProcessingParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessingParametersButtonPushed, true);
            app.ProcessingParametersButton.Text = 'Processing Parameters';
            app.GeneralParametersButton = uibutton(app.LeftPanel, 'push');
            app.GeneralParametersButton.ButtonPushedFcn = createCallbackFcn(app, @BasicParametersButtonPushed, true);
            app.GeneralParametersButton.Text = 'General Parameters';
            app.ImportButton = uibutton(app.LeftPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.OverviewButton = uibutton(app.LeftPanel, 'push');
            app.OverviewButton.ButtonPushedFcn = createCallbackFcn(app, @OverviewButtonPushed, true);
            app.OverviewButton.Text = 'Overview';
            app.ITECLabel = uilabel(app.LeftPanel);
            app.ITECLabel.Text = 'ITEC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ITEC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end